classdef loraQuake < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        messageLabel      matlab.ui.control.Label
        nodeDropDown      matlab.ui.control.DropDown
        invokeButton      matlab.ui.control.Button
        messageEditField  matlab.ui.control.EditField
        nodeAxis          matlab.ui.control.UIAxes
        imageAxis         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        image % Description
        nodes % Description
        edges
        rssi
        % Nnodes = 6 % Description
        % posX = [53  34  51  20  25 65  ];
        % posY = [8.1 10.5 4.5 6.9 4.3 5.5 ];
        % posTextX = [53+2  34-5  51+2  20-18  25-9.5 65+2  ];
        % posTextY = [8.1+1.5 10.5+2.5 2.2 6.9+3 2.2 5.5+1 ];
        % IP = {
        %     '192.168.1.11'...
        %     '192.168.1.12'...
        %     '192.168.1.13'...
        %     '192.168.1.14'...
        %     '192.168.1.15'...
        %     '192.168.1.16'...
        %     }

        Nnodes = 5 % Description
        posX = [53   34  51  20  25];
        posY = [8.1 10.5 4.5 6.9 4.3];
        posTextX = [53+2    34-5  51+2  20-18  25-9.5];
        posTextY = [8.1+1.5 10.5+2.5 2.2 6.9+3 2.2];        
        IP = {
            '192.168.1.11'...
            '192.168.1.12'...
            '192.168.1.13'...
            '192.168.1.14'...
            '192.168.1.15'...
            }


    end
    
    methods (Access = private)
        
        function func(app)
            clc
            close all
            
            
            % Remove title, axis labels, and tick labels
            title(app.imageAxis, []);
            app.imageAxis.Box = "on";
            xlabel(app.imageAxis, []);
            ylabel(app.imageAxis, []);
            app.imageAxis.XAxis.TickLabels = {};
            app.imageAxis.YAxis.TickLabels = {};

            title(app.nodeAxis, []);
            app.nodeAxis.Box = "on";
            xlabel(app.nodeAxis, []);
            ylabel(app.nodeAxis, []);
            app.nodeAxis.XAxis.TickLabels = {};
            app.nodeAxis.YAxis.TickLabels = {};     

            set(app.nodeAxis,'color','none')
            xm = 100;
            ym = 15;
            app.nodeAxis.XLim = [0 xm];
            app.nodeAxis.YLim = [0, ym];

            adjMat = ones(app.Nnodes)-eye(app.Nnodes);
            for indNode = 1:app.Nnodes
                connectedNodes = find(adjMat(indNode,:) == 1);
                for indConnectedNode = 1:numel(connectedNodes)
                    indConnected = connectedNodes(indConnectedNode);
                    hold(app.nodeAxis,"on")
                    alpha = 1;
                    line(app.nodeAxis,[app.posX(indNode) alpha*(app.posX(indConnected)-app.posX(indNode))+app.posX(indNode)],...
                                                                        [app.posY(indNode) alpha*(app.posY(indConnected)-app.posY(indNode))+app.posY(indNode)],'LineStyle',':',...
                                                                        'LineWidth',0.5,'Color',[0.55 0.55 0.55]);
                end
            end

            for indNode = 1:app.Nnodes
                connectedNodes = find(adjMat(indNode,:) == 1);
                for indConnectedNode = 1:numel(connectedNodes)
                    indConnected = connectedNodes(indConnectedNode);
                    alpha = .2;
                    app.edges{indNode,indConnected} = line(app.nodeAxis,[app.posX(indNode) alpha*(app.posX(indConnected)-app.posX(indNode))+app.posX(indNode)],...
                                                                        [app.posY(indNode) alpha*(app.posY(indConnected)-app.posY(indNode))+app.posY(indNode)],'LineWidth',3);

                    app.rssi{indNode,indConnected} = text(app.nodeAxis,0.8*app.posX(indNode)+0.2*app.posX(indConnected), 0.8*app.posY(indNode)+0.2*app.posY(indConnected),'dB','Color','black');
                    app.edges{indNode,indConnected}.Visible = 'off';
                    app.rssi{indNode,indConnected}.Visible = 'off';
                end
            end
                        

            for indNode = 1:app.Nnodes
                hold(app.nodeAxis,"on")
                app.nodes{indNode}.point = plot(app.nodeAxis,app.posX(indNode),app.posY(indNode),'ok','MarkerSize',10,'MarkerFaceColor','none');
                app.nodes{indNode}.hText = text(app.nodeAxis,app.posTextX(indNode), app.posTextY(indNode),...
                                [''],'Color','black');
                
                
                try
                    app.nodes{indNode}.tcpConnection = tcpclient(app.IP{indNode},23,"ConnectTimeout",1);
                    app.nodes{indNode}.connected = 1;
                    disp(['Connected (Node:' num2str(indNode), '), IP:' app.IP{indNode}])
                catch
                    app.nodes{indNode}.connected = 0;
                    disp(['Cannot connect (Node:' num2str(indNode), '), IP:' app.IP{indNode}])
                end
                app.nodes{indNode}.dataAvailable = 0;
            end

            app.UIFigure.Position = [2400, 500, 700, 550];
            app.UIFigure.Position = [0, 0, 700, 550];
            if app.Nnodes == 5
                app.nodeDropDown.Items={'Node 1', 'Node 2', 'Node 3', 'Node 4', 'Node 5'};
            else
                app.nodeDropDown.Items={'Node 1', 'Node 2', 'Node 3', 'Node 4', 'Node 5', 'Node 6'};
            end
            drawnow
            while 1
                tic
                app.communicateWithNodes;
                
                toc
            end

            
        end


        function refreshGui(app,indNode)
            if app.nodes{indNode}.dataAvailable == 1
                app.nodes{indNode}.point.MarkerFaceColor = 'white';
                drawnow
                try
                    if str2num(app.nodes{indNode}.hoppingDistance) == 0
                        app.nodes{indNode}.point.MarkerFaceColor = 'red';
                    else
                        app.nodes{indNode}.point.MarkerFaceColor = 'black';
                    end
                catch
                    disp('?')
                end

                text = ['Node ID: ' app.nodes{indNode}.id];
                text = [text newline 'Slot: ' app.nodes{indNode}.slot];
                text = [text newline 'Hopping distance: ' app.nodes{indNode}.hoppingDistance];
                text = [text newline 'Msg (to be sent): ' app.nodes{indNode}.messageToBeSent];
                text = [text newline 'Last msg (rx''ed): ' app.nodes{indNode}.messageReceived];
                text = [text newline 'Last msg (sent): ' app.nodes{indNode}.messageSent];


                notNeighboutList = [1:app.Nnodes];
                notNeighboutList(notNeighboutList==indNode) = [];
                for indNeighbour = 1:app.nodes{indNode}.numberOfNeigbours   
                    indConnected = str2double(app.nodes{indNode}.neigbour{indNeighbour}.id);
                    notNeighboutList(notNeighboutList==indConnected) = [];
                    app.edges{indNode,indConnected}.Visible = 'on';
                    app.rssi{indNode,indConnected}.Visible = 'on';
                    app.rssi{indNode,indConnected}.String = app.nodes{indNode}.neigbour{indNeighbour}.rssi;
                end
                
                for indNeighbour = notNeighboutList
                    app.edges{indNode,indNeighbour}.Visible = 'off';
                    app.rssi{indNode,indNeighbour}.Visible = 'off';
                end                    


            else
                app.nodes{indNode}.point.MarkerFaceColor = 'white';
                %text = ['No Wi-Fi connection'];
                text = ['Node is off'];
                notNeighboutList = [1:app.Nnodes];
                notNeighboutList(notNeighboutList==indNode) = [];
                for indNeighbour = notNeighboutList
                    app.edges{indNode,indNeighbour}.Visible = 'off';
                    app.rssi{indNode,indNeighbour}.Visible = 'off';
                end                       
            end

            app.nodes{indNode}.hText.String = text;
            drawnow
        end

        function communicateWithNodes(app,~,~)
            for indNode = 1:app.Nnodes
                if app.nodes{indNode}.connected == 1
                    response = 'notdone';
                    app.nodes{indNode}.tcpConnection.write(['info&'],"string")

                    disp(['Request info @ Node' num2str(indNode)])

                    indNeigbour = 1;
                    pause(.2)
                    timeOut=tic;
                    app.nodes{indNode}.dataAvailable = 1;
                    while(~strcmp(response,'done'))
                        if (app.nodes{indNode}.tcpConnection.NumBytesAvailable)
                            response = app.nodes{indNode}.tcpConnection.readline();
                            responseSplit = split(response);

                            switch responseSplit{1}
                                case 'myInfo'
                                    app.nodes{indNode}.id = responseSplit{3};
                                    app.nodes{indNode}.slot = responseSplit{5};
                                    if strcmp(responseSplit{7},'127')
                                        app.nodes{indNode}.hoppingDistance = 'N/A';
                                    else
                                        app.nodes{indNode}.hoppingDistance = responseSplit{7};
                                    end
                                    app.nodes{indNode}.numberOfNeigbours = 0;

                                case 'neighbour'
                                    app.nodes{indNode}.neigbour{indNeigbour}.id = responseSplit{3};
                                    app.nodes{indNode}.neigbour{indNeigbour}.slot = responseSplit{5};
                                    if strcmp(responseSplit{7},'127')
                                        app.nodes{indNode}.neigbour{indNeigbour}.hoppingDistance = 'N/A';
                                    else
                                        app.nodes{indNode}.neigbour{indNeigbour}.hoppingDistance = responseSplit{7};
                                    end
                                    app.nodes{indNode}.neigbour{indNeigbour}.rssi = responseSplit{9};
                                    app.nodes{indNode}.numberOfNeigbours = indNeigbour;
                                    indNeigbour =  indNeigbour + 1;

                                case 'messageToBeSent'    
                                    if isempty(responseSplit{2})
                                        app.nodes{indNode}.messageToBeSent = 'N/A';
                                    else
                                        responseChar = char(response);
                                        app.nodes{indNode}.messageToBeSent = (responseChar(17:end));
                                    end


                                case 'messageSent'
                                    if isempty(responseSplit{2})
                                        app.nodes{indNode}.messageSent = 'N/A';
                                    else
                                        responseChar = char(response);
                                        app.nodes{indNode}.messageSent = (responseChar(13:end));
                                    end


                                case 'messageReceived'
                                    if isempty(responseSplit{2})
                                        app.nodes{indNode}.messageReceived = 'N/A';
                                    else
                                        responseChar = char(response);
                                        app.nodes{indNode}.messageReceived = (responseChar(17:end));
                                    end
                            end

                        else
                            if toc(timeOut)>3
                                response='done';
                                app.nodes{indNode}.connected = 0;
                                app.nodes{indNode}.dataAvailable = 0;
                                disp('>timeout')
                            end
                        end
                    end
                else
                    app.nodes{indNode}.dataAvailable = 0;
                    try
                        app.nodes{indNode}.tcpConnection = tcpclient(app.IP{indNode},23,"ConnectTimeout",1);
                        app.nodes{indNode}.connected = 1;
                        disp(['Connected (Node:' num2str(indNode), '), IP:' app.IP{indNode}])
                    catch
                        app.nodes{indNode}.connected = 0;
                        disp(['Cannot connect (Node:' num2str(indNode), '), IP:' app.IP{indNode}])
                    end

                end
                app.refreshGui(indNode);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.func()
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)

            app.imageAxis.Position = [0 app.UIFigure.Position(4)*10/100 app.UIFigure.Position(3)  app.UIFigure.Position(4)*90/100];
            app.nodeAxis.Position = app.imageAxis.Position;

            fontsize = round(app.UIFigure.Position(3)/75);

            app.messageLabel.Position = [
                5 
                app.UIFigure.Position(4)*0.8/100 
                app.UIFigure.Position(3)*10/100 
                app.UIFigure.Position(4)*5/100];
            app.messageLabel.FontSize = fontsize;
            app.messageEditField.Position = [
                app.messageLabel.Position(1)+app.messageLabel.Position(3)  
                app.UIFigure.Position(4)*0.8/100 
                app.UIFigure.Position(3)*60/100 
                app.UIFigure.Position(4)*5/100];
            app.messageEditField.FontSize = fontsize;
            app.nodeDropDown.Position = [
                app.messageEditField.Position(1)++app.messageEditField.Position(3) 
                app.UIFigure.Position(4)*0.8/100  
                app.UIFigure.Position(3)*20/100 
                app.UIFigure.Position(4)*5/100];
            app.nodeDropDown.FontSize = fontsize;
            app.invokeButton.Position = [
                app.nodeDropDown.Position(1)++app.nodeDropDown.Position(3) 
                app.UIFigure.Position(4)*0.8/100  
                app.UIFigure.Position(3)*9/100-5
                app.UIFigure.Position(4)*5/100];
            app.invokeButton.FontSize = fontsize;


            adjMat = ones(app.Nnodes)-eye(app.Nnodes);
            for indNode = 1:app.Nnodes
                try
                    app.nodes{indNode}.point.MarkerSize = round(app.UIFigure.Position(3)/55);
                    app.nodes{indNode}.hText.FontSize = round(app.UIFigure.Position(3)/60);

                    connectedNodes = find(adjMat(indNode,:) == 1);
                    for indConnectedNode = 1:numel(connectedNodes)
                        indConnected = connectedNodes(indConnectedNode);
                        app.rssi{indNode,indConnected}.FontSize  = round(app.UIFigure.Position(3)/65);
                    end
                catch
                end
            end

       
            % Display image and stretch to fill axes
            app.image = imshow('rubble.png', 'Parent', app.imageAxis, ...
                'XData', [1 app.imageAxis.Position(3)], ...
                'YData', [1 app.imageAxis.Position(4)]);
            % Set limits of axes
            app.imageAxis.XLim = [0 app.image.XData(2)];
            app.imageAxis.YLim = [0 app.image.YData(2)];


        end

        % Button pushed function: invokeButton
        function invokeButtonPushed(app, event)
            text = ['trig ' app.messageEditField.Value '&'];
            switch app.nodeDropDown.Value
                case "Node 1"
                    app.nodes{1}.tcpConnection.write(text,"string")
                case "Node 2"
                    app.nodes{2}.tcpConnection.write(text,"string")
                case "Node 3"
                    app.nodes{3}.tcpConnection.write(text,"string")
                case "Node 4"
                    app.nodes{4}.tcpConnection.write(text,"string")
                case "Node 5"
                    app.nodes{5}.tcpConnection.write(text,"string")
                case "Node 6"
                    app.nodes{6}.tcpConnection.write(text,"string")
            end
                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.imageAxis = uiaxes(app.UIFigure);
            title(app.imageAxis, 'Title')
            xlabel(app.imageAxis, 'X')
            ylabel(app.imageAxis, 'Y')
            zlabel(app.imageAxis, 'Z')
            app.nodeAxis = uiaxes(app.UIFigure);
            title(app.nodeAxis, 'Title')
            xlabel(app.nodeAxis, 'X')
            ylabel(app.nodeAxis, 'Y')
            zlabel(app.nodeAxis, 'Z')
            app.messageEditField = uieditfield(app.UIFigure, 'text');
            app.messageEditField.Value = 'Enter a message...';
            app.invokeButton = uibutton(app.UIFigure, 'push');
            app.invokeButton.ButtonPushedFcn = createCallbackFcn(app, @invokeButtonPushed, true);
            app.invokeButton.Text = 'Invoke';
            app.nodeDropDown = uidropdown(app.UIFigure);
            app.messageLabel = uilabel(app.UIFigure);
            app.messageLabel.Text = 'Message:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loraQuake

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end